classdef CompDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SlicesnumberdisplayedEditField  matlab.ui.control.NumericEditField
        SlicesnumberdisplayedEditFieldLabel  matlab.ui.control.Label
        GetmaxminfromDropDown           matlab.ui.control.DropDown
        GetmaxminfromDropDownLabel      matlab.ui.control.Label
        ColorbarpropertiesLabel         matlab.ui.control.Label
        ChoosethesliceCheckBox          matlab.ui.control.CheckBox
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
    end


    properties (Access = public)
        MainForm % Description
    end

    methods (Access = private)

        function updateDisplay(app)

            % Очищаем содержимое панели
            delete(app.Panel.Children);

            % Размеры панели
            panelPos = app.Panel.Position;
            % Задаём высоту области для colorbar – увеличено до 50 пикселей
            colorbarHeight = 50;
            % Доступная высота для изображений (выше colorbar)
            imagesAreaHeight = panelPos(4) - colorbarHeight;

            % Получаем имена массивов из drop-down (все выбранные массивы)
            arrayNames = app.GetmaxminfromDropDown.Items;
            nArrays = numel(arrayNames);

            % Количество отображаемых срезов (по горизонтали)
            nSlices = round(app.SlicesnumberdisplayedEditField.Value);

            % Начальный срез для отображения из слайдера
            startSlice = round(app.Slider.Value);

            % Значения для общего колорбара
            cmin = app.MinEditField.Value;
            cmax = app.MaxEditField.Value;

            % Вычисляем размеры для осей изображений
            axWidth = panelPos(3) / nSlices;
            axHeight = imagesAreaHeight / nArrays;

            % Для каждого массива (отображаем строкой)
            for i = 1:nArrays
                % Извлекаем имя переменной (без размеров) для отображения
                displayName = app.extractVarName(arrayNames{i});
                % Получаем 3D массив по имени из base
                arr = normalizeVolumeForDisplay(evalin('base', displayName));
                totalSlices = size(arr, 3);

                % Для каждого среза в ряду
                for j = 1:nSlices
                    sliceIndex = startSlice + j - 1;
                    if sliceIndex > totalSlices
                        break;
                    end

                    % Вычисляем позицию для оси.
                    left = (j-1) * axWidth;
                    % Располагаем изображения в зоне выше colorbar.
                    bottom = colorbarHeight + (nArrays - i) * axHeight;
                    ax = uiaxes(app.Panel, 'Position', [left, bottom, axWidth, axHeight]);
                    img = imagesc(ax, arr(:,:,sliceIndex));
                    % Применяем единые пределы цветовой шкалы ко всем изображениям
                    ax.CLim = [cmin, cmax];
                    ax.XTick = [];
                    ax.YTick = [];
                    colormap(ax, jet);

                    % Устанавливаем заголовок с именем массива и номером слоя
                    title(ax, sprintf('%s, z = %d', displayName, sliceIndex), 'FontSize', 10);
                end
            end

            % Создаём горизонтальный colorbar в нижней части панели
            cbax = uiaxes(app.Panel, 'Position', [0, 0, panelPos(3), colorbarHeight]);
            % Создаём градиент для colorbar (50 строк соответствует высоте области)
            grad = repmat(linspace(cmin, cmax, 256), 50, 1);
            imagesc(cbax, grad);
            cbax.XTick = linspace(1,256,5);
            cbax.XTickLabel = num2cell(linspace(cmin, cmax, 5));
            cbax.YTick = [];
            colormap(cbax, jet);
        end
    end
    
    methods (Static)
        function varName = extractVarName(fullStr)
            % Разбиваем строку по пробелу и берём первый токен как имя переменной
            tokens = strsplit(fullStr, ' ');
            varName = tokens{1};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, argument)
            % Сохраняем ссылку на главное окно
            app.MainForm = argument;

            % Перенос выбранных элементов из главного окна (AvaliablefilesListBox) в drop-down
            selectedItems = app.MainForm.AvaliablefilesListBox.Value;

            % Если список пуст
            if isempty(selectedItems)
                uialert(app.UIFigure, 'Не выбраны элементы!', 'Ошибка');
                return;
            end

            app.GetmaxminfromDropDown.Items = selectedItems;
            % Теперь точно можно обращаться к первому элементу
            varName = app.extractVarName(selectedItems{1});
            inBase = evalin('base', sprintf('exist(''%s'', ''var'')', varName));
            if ~inBase
                uialert(app.UIFigure, ...
                    sprintf('Переменная "%s" не найдена в базовом workspace.', varName), ...
                    'Ошибка');
                return;
            end
            defaultArray = normalizeVolumeForDisplay(evalin('base', varName));
            app.MinEditField.Value = min(defaultArray(:));
            app.MaxEditField.Value = max(defaultArray(:));

            % Инициализируем слайдер (от 1 до количества слоев первого массива)
            app.Slider.Limits = [1, size(defaultArray,3)];
            app.Slider.Value = 1;

            % Отключаем возможность редактирования номера среза по умолчанию
            app.EditField.Enable = 'off';

            % Первоначальное отображение
            updateDisplay(app);
        end

        % Value changed function: GetmaxminfromDropDown
        function GetMaxMinDropDownValueChanged(app, event)
            % Получаем выбранный массив
            selectedArray = app.GetmaxminfromDropDown.Value;
            arr = normalizeVolumeForDisplay(evalin('base', app.extractVarName(selectedArray)));

            % Обновляем глобальные min/max для всего массива
            app.MinEditField.Value = min(arr(:));
            app.MaxEditField.Value = max(arr(:));

            % Обновляем слайдер, задавая его лимиты числом слоев массива
            app.Slider.Limits = [1, size(arr, 3)];
            app.Slider.Value = 1;

            updateDisplay(app);
        end

        % Value changed function: SlicesnumberdisplayedEditField
        function SlicesNumberValueChanged(app, event)
            updateDisplay(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            updateDisplay(app);
        end

        % Value changed function: ChoosethesliceCheckBox
        function ChooseSliceCheckBoxChanged(app, event)
            if app.ChoosethesliceCheckBox.Value
                % Разрешаем ввод номера среза
                app.EditField.Enable = 'on';
            else
                % Отключаем редактирование и сбрасываем глобальные min/max для всего массива
                app.EditField.Enable = 'off';
                selectedArray = app.GetmaxminfromDropDown.Value;
                arr = normalizeVolumeForDisplay(evalin('base', app.extractVarName(selectedArray)));
                app.MinEditField.Value = min(arr(:));
                app.MaxEditField.Value = max(arr(:));
            end
            updateDisplay(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            if app.ChoosethesliceCheckBox.Value
                sliceIndex = round(app.EditField.Value);
                selectedArray = app.GetmaxminfromDropDown.Value;
                arr = normalizeVolumeForDisplay(evalin('base', app.extractVarName(selectedArray)));
                totalSlices = size(arr, 3);
                if sliceIndex < 1 || sliceIndex > totalSlices
                    uialert(app.UIFigure, sprintf('Номер слоя должен быть от 1 до %d.', totalSlices), 'Ошибка');
                    return;
                end
                % Обновляем min и max для выбранного слоя
                sliceData = arr(:,:,sliceIndex);
                app.MinEditField.Value = min(sliceData(:));
                app.MaxEditField.Value = max(sliceData(:));
                updateDisplay(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '№';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoosethesliceCheckBox = uicheckbox(app.UIFigure);
            app.ChoosethesliceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChooseSliceCheckBoxChanged, true);
            app.ChoosethesliceCheckBox.Tooltip = {'При выборе номера слоя colorbar отнормируется по выбранному слою, в не по всему массиву'};
            app.ChoosethesliceCheckBox.Text = 'Choose the slice';
            app.ColorbarpropertiesLabel = uilabel(app.UIFigure);
            app.ColorbarpropertiesLabel.Text = 'Colorbar properties';
            app.GetmaxminfromDropDownLabel = uilabel(app.UIFigure);
            app.GetmaxminfromDropDownLabel.Text = 'Get max&min from';
            app.GetmaxminfromDropDown = uidropdown(app.UIFigure);
            app.GetmaxminfromDropDown.ValueChangedFcn = createCallbackFcn(app, @GetMaxMinDropDownValueChanged, true);
            app.SlicesnumberdisplayedEditFieldLabel = uilabel(app.UIFigure);
            app.SlicesnumberdisplayedEditFieldLabel.Text = 'Slices number displayed';
            app.SlicesnumberdisplayedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlicesnumberdisplayedEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesNumberValueChanged, true);
            app.SlicesnumberdisplayedEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end