classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        AvaliablefilesListBox          matlab.ui.control.ListBox
        AvaliablefilesLabel            matlab.ui.control.Label
        ZsizeEditField                 matlab.ui.control.NumericEditField
        ZsizeEditFieldLabel            matlab.ui.control.Label
        YsizeEditField                 matlab.ui.control.NumericEditField
        YsizeEditFieldLabel            matlab.ui.control.Label
        XsizeEditField                 matlab.ui.control.NumericEditField
        XsizeEditFieldLabel            matlab.ui.control.Label
        OpenfileButton                 matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        SpectrumButton                 matlab.ui.control.Button
        CalculationtypeListBox_2       matlab.ui.control.ListBox
        CalculationtypeListBox_2Label  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ComparefilesButton             matlab.ui.control.Button
        ShowfileButton                 matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        ClearselectionButton           matlab.ui.control.Button
        AvaliablemasksListBox_2        matlab.ui.control.ListBox
        AvaliablemasksListBox_2Label   matlab.ui.control.Label
        SlicenumberEditField           matlab.ui.control.NumericEditField
        SlicenumberEditFieldLabel      matlab.ui.control.Label
        CreatemaskButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        MyTimer
    end

    methods (Access = private)

        function initTimer(app)
            % Создаём таймер
            app.MyTimer = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period',2, ...
                'TimerFcn',@(~,~)app.onTimerTick() ...
                );
            start(app.MyTimer);
        end

        function onTimerTick(app)
            % Получаем информацию о переменных из базового workspace
            wsInfo = evalin('base', 'whos');
            maskNames = {};

            % Проходим по всем переменным и выбираем те, что типа logical и размерность 2D
            for i = 1:length(wsInfo)
                if strcmp(wsInfo(i).class, 'logical') && numel(wsInfo(i).size) == 2
                    maskNames{end+1} = wsInfo(i).name; %#ok<AGROW>
                end
            end

            % Если массивов не найдено, можно вывести сообщение или задать заглушку
            if isempty(maskNames)
                maskNames = {'Нет масок'};
            end

            % Обновляем список доступных масок в приложении
            app.AvaliablemasksListBox_2.Items = maskNames;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenfileButton
        function buttonOpenFilePushed(app, event)
            openFileCallback(app.XsizeEditField, app.YsizeEditField, app.ZsizeEditField, app.AvaliablefilesListBox);
            updateSpectrumButtonState(app.AvaliablefilesListBox, app.SpectrumButton); 
        end

        % Button pushed function: ShowfileButton
        function buttonShowFilePushed(app, event)
            if isempty(app.AvaliablefilesListBox.Value)
                uialert(app.UIFigure, 'Не выбран ни один элемент.', 'Ошибка');
                return;
            end
            displaySelectedCallback(app.AvaliablefilesListBox,app.AvaliablemasksListBox_2);
        end

        % Button pushed function: ComparefilesButton
        function buttonCompareFilesPushed(app, event)
            CompDisplay(app);
        end

        % Button pushed function: CreatemaskButton
        function buttonCreateMaskPushed(app, event)
            uialert(app.UIFigure, 'Старт', 'Ошибка');
            segmentLayerCallback(app.AvaliablefilesListBox, app.SlicenumberEditField);
            uialert(app.UIFigure, 'Стоп', 'Ошибка');
            initTimer(app);
        end

        % Button pushed function: CalculateButton
        function buttonCalculatePressed(app, event)
            Calculation(app);
        end

        % Button pushed function: ClearselectionButton
        function ClearMaskSelectionPressed(app, event)
            app.AvaliablemasksListBox_2.Value = {};
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            openSpectralInteractionCallback(app.AvaliablefilesListBox);
        end

        % Value changed function: AvaliablefilesListBox
        function AvaliablefilesListBoxValueChanged(app, event)
            updateSpectrumButtonState(app.AvaliablefilesListBox, app.SpectrumButton);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x', 276};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenfileButton = uibutton(app.LeftPanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @buttonOpenFilePushed, true);
            app.OpenfileButton.Tooltip = {'Чтобы загрузить файл введите размерность изображения'};
            app.OpenfileButton.Text = 'Open file';
            app.XsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.XsizeEditFieldLabel.Text = 'X size';
            app.XsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XsizeEditField.Value = 128;
            app.YsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.YsizeEditFieldLabel.Text = 'Y size';
            app.YsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YsizeEditField.Value = 128;
            app.ZsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.ZsizeEditFieldLabel.Text = 'Z size';
            app.ZsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZsizeEditField.Value = 16;
            app.AvaliablefilesLabel = uilabel(app.LeftPanel);
            app.AvaliablefilesLabel.Text = 'Avaliable files';
            app.AvaliablefilesListBox = uilistbox(app.LeftPanel);
            app.AvaliablefilesListBox.Items = {};
            app.AvaliablefilesListBox.Multiselect = 'on';
            app.AvaliablefilesListBox.ValueChangedFcn = createCallbackFcn(app, @AvaliablefilesListBoxValueChanged, true);
            app.AvaliablefilesListBox.Value = {};
            app.CenterPanel = uipanel(app.GridLayout);
            app.ShowfileButton = uibutton(app.CenterPanel, 'push');
            app.ShowfileButton.ButtonPushedFcn = createCallbackFcn(app, @buttonShowFilePushed, true);
            app.ShowfileButton.Tooltip = {'Выделите файл из формы Avaliable files, чтобы открыть'};
            app.ShowfileButton.Text = 'Show file';
            app.ComparefilesButton = uibutton(app.CenterPanel, 'push');
            app.ComparefilesButton.ButtonPushedFcn = createCallbackFcn(app, @buttonCompareFilesPushed, true);
            app.ComparefilesButton.Tooltip = {'Выделите файлы из формы Avaliable files, чтобы сравнить'};
            app.ComparefilesButton.Text = 'Compare files';
            app.CalculateButton = uibutton(app.CenterPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @buttonCalculatePressed, true);
            app.CalculateButton.Tooltip = {'Выберите тип расчета в форме Calculation type'};
            app.CalculateButton.Text = 'Calculate';
            app.CalculationtypeListBox_2Label = uilabel(app.CenterPanel);
            app.CalculationtypeListBox_2Label.Text = 'Calculation type';
            app.CalculationtypeListBox_2 = uilistbox(app.CenterPanel);
            app.CalculationtypeListBox_2.Items = {'SNR', 'MEAN', 'CNR'};
            app.CalculationtypeListBox_2.Value = 'CNR';
            app.SpectrumButton = uibutton(app.CenterPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Enable = 'off';
            app.SpectrumButton.Text = 'Spectrum';
            app.RightPanel = uipanel(app.GridLayout);
            app.CreatemaskButton = uibutton(app.RightPanel, 'push');
            app.CreatemaskButton.ButtonPushedFcn = createCallbackFcn(app, @buttonCreateMaskPushed, true);
            app.CreatemaskButton.Tooltip = {'Чтобы создать маску выберите файл в форме Avaliable files и введите slice number'};
            app.CreatemaskButton.Text = 'Create mask';
            app.SlicenumberEditFieldLabel = uilabel(app.RightPanel);
            app.SlicenumberEditFieldLabel.Text = 'Slice number';
            app.SlicenumberEditField = uieditfield(app.RightPanel, 'numeric');
            app.SlicenumberEditField.Value = 1;
            app.AvaliablemasksListBox_2Label = uilabel(app.RightPanel);
            app.AvaliablemasksListBox_2Label.Text = 'Avaliable masks';
            app.AvaliablemasksListBox_2 = uilistbox(app.RightPanel);
            app.AvaliablemasksListBox_2.Items = {};
            app.AvaliablemasksListBox_2.Value = {};
            app.ClearselectionButton = uibutton(app.RightPanel, 'push');
            app.ClearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMaskSelectionPressed, true);
            app.ClearselectionButton.Text = 'Clear selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end